classdef Eq2800 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Panel                        matlab.ui.container.Panel
        TabGroup                     matlab.ui.container.TabGroup
        MainTab                      matlab.ui.container.Tab
        Label                        matlab.ui.control.Label
        EditField                    matlab.ui.control.NumericEditField
        Label_2                      matlab.ui.control.Label
        SiecmecZone                  matlab.ui.control.DropDown
        Label_3                      matlab.ui.control.Label
        FrameType                    matlab.ui.control.DropDown
        Label_4                      matlab.ui.control.Label
        ImportantLevel               matlab.ui.control.DropDown
        Label_5                      matlab.ui.control.Label
        LandType                     matlab.ui.control.DropDown
        Label_11                     matlab.ui.control.Label
        EffectSwitch                 matlab.ui.control.Switch
        Label_12                     matlab.ui.control.Label
        Slider_2                     matlab.ui.control.Slider
        EditField_2                  matlab.ui.control.NumericEditField
        UITable2                     matlab.ui.control.Table
        Button_2                     matlab.ui.control.Button
        UITable3                     matlab.ui.control.Table
        Label_9                      matlab.ui.control.Label
        Label_8                      matlab.ui.control.Label
        Button_3                     matlab.ui.control.Button
        ProgramOutputTab             matlab.ui.container.Tab
        ExportResultButton           matlab.ui.control.Button
        UITable5                     matlab.ui.control.Table
        ClearResultButton            matlab.ui.control.Button
        SpectrumTab                  matlab.ui.container.Tab
        UITable4                     matlab.ui.control.Table
        ClearTableButton             matlab.ui.control.Button
        ExportResultsButton          matlab.ui.control.Button
        CalculateBButton             matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        AboutTab                     matlab.ui.container.Tab
        Image                        matlab.ui.control.Image
        SAYEHGroupLabel              matlab.ui.control.Label
        Label_14                     matlab.ui.control.Label
        DeveloperMostafaJafariLabel  matlab.ui.control.Label
        wwwSha2wirenLabel            matlab.ui.control.Label
        Label_15                     matlab.ui.control.Label
        Label_16                     matlab.ui.control.Label
        Label_13                     matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: Slider_2
        function Slider_2ValueChanged(app, event)
            value = app.Slider_2.Value;
            app.EditField_2.Value=value;
            %string(value);
        end

        % Value changed function: EditField_2
        function EditField_2ValueChanged(app, event)
            value = app.EditField_2.Value;
            app.Slider_2.Value=value;
        end

        % Button pushed function: Button_2
        function Button_2Pushed(app, event)
            H=app.EditField.Value;
            if isempty(H)
                warndlg 'ارتفاع ساختمان وارد شود!'
            end
            EffSwitch=app.EffectSwitch.Value;
            dm = 1; %The Effect of Miyangab
            if strcmp(EffSwitch,'بله')
                dm=0.8;
            end
            Tmax=app.EditField_2.Value;
            if isempty(Tmax)
                warndlg 'پریود تحلیل ساختمان وارد شود!'
            end
            pa=app.SiecmecZone.Value;%Seismic Zone & A
            switch pa
                case 'پهنه با خطر نسبی خیلی زیاد'
                    A=0.35;
                case 'پهنه با خطر نسبی  زیاد'
                    A=0.30;
                case 'پهنه با خطر نسبی متوسط'
                    A=0.25;
                case 'پهنه با خطر نسبی کم'
                    A=0.20;
            end
            ch=app.ImportantLevel.Value; %Important of building
            switch ch
                case 'با اهمیت خیلی زیاد'
                    Ie=1.4;
                case 'با اهمیت زیاد'
                    Ie=1.2;
                case 'با اهمیت متوسط'
                    Ie=1;
                case 'با اهمیت کم'
                    Ie=0.80;
            end
            Fr=app.FrameType.Value; %Frame Type & Ru&Omeg0 & Cd
            switch Fr
                case '1- دیوارهای برشی بتن آرمه ویژه'
                    ft=1;Ru=6;Omega0=2.5;Cd=5;Hmax=50;
                    T = 1 * 0.05 * H ^ (0.75);
                    T = 1.25 * T;
                case '2- دیوارهای برشی بتن آرمه متوسط'
                    ft=0;Ru=5;Omega0=2.5;Cd=4;Hmax=35;
                    T = 1 * 0.05 * H ^ (0.75);
                    T = 1.25 * T;
                case '3- دیوارهای برشی بتن آرمه معمولی'
                    ft=0;Ru=4;Omega0=2.5;Cd=3;Hmax=0;
                    if A<=0.25 && Ie<=1
                        Hmax=15;
                    end
                    if (Hmax==0) || (Hmax<H)
                        errordlg 'قاب انتخاب شده برای این ارتفاع مناسب نمی باشد' 'لطفا سیستم جانبی مناسب انتخاب کنید'
                        app.Label_13.Text='قاب انتخاب شده برای این ارتفاع مناسب نمی باشد، لطفا سیستم جانبی مناسب انتخاب کنید';
                        app.Label_13.FontColor='red';
                    end
                    T = 1 * 0.05 * H ^ (0.75);
                    T = 1.25 * T;
                case '4- دیوارهای برشی یا مصالح بنایی مسلح'
                    ft=0;Ru=3;Omega0=2.5;Cd=2.5;Hmax=15;
                    T = 1 * 0.05 * H ^ (0.75);
                    T = 1.25 * T
                case '5- مهاربند واگرای ویژه فولادی'
                    ft=0;Ru=7;Omega0=2;Cd=4;Hmax=50;
                    T = dm * 0.08 * H ^ (0.75);
                    T = 1.25 * T;
                case '6-مهاربندی کمانش تاب'
                    ft=0;Ru=7;Omega0=2.5;Cd=5;Hmax=50;
                    T = 1 * 0.05 * H ^ (0.75);
                    T = 1.25 * T;
                case '7- مهاربند همگرای معمولی فولادی'
                    ft=0;Ru=3.5;Omega0=2;Cd=3.5;Hmax=15;
                    T = 1 * 0.05 * H ^ (0.75);
                    T = 1.25 * T;
                case '8- مهاربند همگرای ویژه فولادی'
                    ft=1;Ru=5.5;Omega0=2;Cd=5;Hmax=50;
                    T = 1 * 0.05 * H ^ (0.75);
                    T = 1.25 * T;
                case '1- قاب خمشی بتن آرمه ویژه'
                    ft=1;Ru=7.5;Omega0=3;Cd=5.5;Hmax=200;
                    T = dm * 0.05 * H ^ (0.9);
                    T = 1.25 * T;
                case '2- قاب خمشی بتن آرمه متوسط'
                    ft=0;Ru=5;Omega0=3;Cd=4.5;Hmax=35;
                    T = dm * 0.05 * H ^ (0.9);
                    T = 1.25 * T;
                case '3- قاب خمشی بتن آرمه معمولی'
                    ft=0;Ru=3;Omega0=3;Cd=2.5;Hmax=0;
                    if A<=0.25 && Ie<=1
                        Hmax=15;
                    end
                    if Hmax==0 || Hmax<H
                        errordlg 'قاب انتخاب شده برای این ارتفاع مناسب نمی باشد' 'لطفا سیستم جانبی مناسب انتخاب کنید'
                        app.Label_13.Text='قاب انتخاب شده برای این ارتفاع مناسب نمی باشد، لطفا سیستم جانبی مناسب انتخاب کنید'
                        app.Label_13.FontColor='red';
                    end
                    T = dm * 0.05 * H ^ (0.9);
                    T = 1.25 * T;
                case '4- قاب خمشی فولادی ویژه'
                    ft=1;Ru=7.5;Omega0=3;Cd=5.5;Hmax=200;
                    T = dm * 0.08 * H ^ (0.75);
                    T = 1.25 * T;
                case '5- قاب خمشی فولادی متوسط'
                    ft=0;Ru=5;Omega0=3;Cd=4;Hmax=50;
                    T = dm * 0.08 * H ^ (0.75);
                    T = 1.25 * T;
                case '6- قاب خمشی فولادی معمولی'
                    ft=0;Ru=3.5;Omega0=3;Cd=3;Hmax=0;
                    if A<=0.25 && Ie<=1
                        Hmax=15;
                    end
                    if Hmax==0 || Hmax<H
                        errordlg 'قاب انتخاب شده برای این ارتفاع مناسب نمی باشد' 'لطفا سیستم جانبی مناسب انتخاب کنید'
                        app.Label_13.Text='قاب انتخاب شده برای این ارتفاع مناسب نمی باشد، لطفا سیستم جانبی مناسب انتخاب کنید'
                        app.Label_13.FontColor='red';
                    end
                    T = dm * 0.08 * H ^ (0.75);
                    T = 1.25 * T;
                case '1- قاب خمشی ویژه(فولادی یا بتنی)+ دیوارهای برشی بتن آرمه ویژه'
                    ft=1;Ru=7.5;Omega0=2.5;Cd=5.5;Hmax=200;
                    T = 1 * 0.05 * H ^ (0.75);
                    T = 1.25 * T;
                case '2- قاب خمشی بتن آرمه متوسط+ دیوارهای برشی بتن آرمه ویژه'
                    ft=1;Ru=6.5;Omega0=2.5;Cd=5;Hmax=70;
                    T = 1 * 0.05 * H ^ (0.75);
                    T = 1.25 * T;
                case '3- قاب خمشی بتن آرمه متوسط+ دیوارهای برشی بتن آرمه متوسط'
                    ft=0;Ru=6;Omega0=2.5;Cd=4.5;Hmax=50;
                    T = 1 * 0.05 * H ^ (0.75);
                    T = 1.25 * T;
                case '4- قاب خمشی فولادی متوسط+ دیوارهای برشی بتن آرمه متوسط'
                    ft=0;Ru=6;Omega0=2.5;Cd=4.5;Hmax=50;
                    T = 1 * 0.05 * H ^ (0.75);
                    T = 1.25 * T;
                case '5- قاب خمشی فولادی ویژه+ مهاربند واگرای ویژه'
                    ft=1;Ru=7.5;Omega0=2.5;Cd=4;Hmax=200;
                    T = dm * 0.08 * H ^ (0.75);
                    T = 1.25 * T;
                case '6- قاب خمشی فولادی متوسط+ مهاربند واگرای ویژه'
                    ft=1;Ru=6;Omega0=2.5;Cd=5;Hmax=70;
                    T = dm * 0.08 * H ^ (0.75);
                    T = 1.25 * T;
                case '7- قاب خمشی فولادی ویژه+ مهاربند همگرای ویژه'
                    ft=1;Ru=7;Omega0=2.5;Cd=5.5;Hmax=200;
                    T = 1 * 0.05 * H ^ (0.75);
                    T = 1.25 * T;
                case '8- قاب خمشی فولادی متوسط+ مهاربند همگرای ویژه'
                    ft=1;Ru=6;Omega0=2.5;Cd=5;Hmax=70;
                    T = 1 * 0.05 * H ^ (0.75);
                    T = 1.25 * T;
                case '1-سازه های فولادی یا بتن آرمه ویژه'
                    ft=1;Ru=2;Omega0=1.5;Cd=2;Hmax=10;
                    T = dm * 0.08 * H ^ (0.75);
                    T = 1.25 * T;
            end
            if T>Tmax
                T=Tmax;
            end
            Gt=app.LandType.Value;%Parametres of Table 2-2
            switch Gt
                case 'I'
                    T0=0.1;Ts=0.4;
                    if A<=0.25
                        S =1.5;S0=1;
                    end
                case 'II'
                    T0=0.1;Ts=0.5;
                    if A<=0.25
                        S =1.5;S0=1;
                    end
                case 'III'
                    T0=0.15;Ts=0.7;
                    if A<=0.25
                        S =1.5;S0=1;
                    else
                        S=1.5;S0=1;
                    end
                case 'IV'
                    T0=0.15;Ts=1;
                    if A<=0.25
                        S =1.5;S0=1;
                    else
                        S=1.5;S0=1;
                    end
            end
            if A>=0.3%=======================
                if T<=Ts
                    N = 1;
                elseif T<=4
                    N = 0.7 / (4 - Ts) * (T - Ts) + 1;
                else
                    N = 1.7;
                end
            else
                if T <= Ts
                    N = 1;
                elseif T <= 4
                    N = 0.4 / (4 - Ts) * (T - Ts) + 1;
                else
                    N = 1.4;
                end
            end
            if T<=T0 %++++===========
                B1 = S0 + (S - S0 + 1) * (T / T0);
            elseif T <= Ts
                B1 = S + 1;
            else
                B1 = (S + 1) * (Ts / T);
            end
            B = B1 * N;
            Cmin = 0.12 * A * Ie;%++++===========
            C = round((A * B * Ie / Ru),3);
            if C < Cmin
                C = Cmin;
                app.Label_13.Text='در محاسبه نیروی زلزله ضریب زلزله حداقل جایگزین شد'
            end
            if T<=0.5
                K=1;
            elseif T<=2.5
                K = 0.5 * T + 0.75;
            else
                K = 2
            end
            
            
            if Ie==1.4
                T=T;
                app.Label_13.Text='در محاسبه تغییر مکان بند 3-5-3 آئین نامه 2800 ویرایش 4 اعمال شده است.'
            else
                T = Tmax;
            end
            if A >= 0.3
                if T <= Ts
                    N=1;
                elseif T <= 4
                    N = 0.7 / (4 - Ts) * (T - Ts) + 1;
                else
                    N = 1.7;
                end
            else
                if T <= Ts
                    N = 1;
                elseif T <= 4
                    N = 0.4 / (4 - Ts) * (T - Ts) + 1;
                else
                    N = 1.4;
                end
            end
            if T<= T0
                B1 = S0 + (S - S0 + 1) * (T / T0);
            elseif T <= Ts
                B1 = S + 1;
            else
                B1 = (S + 1) * (Ts / T);
            end
            B = B1 * N;
            Cmin = 0.12 * A * Ie
            C = (A * B * Ie / Ru)
            if C < Cmin
                C = Cmin;
                app.Label_13.Text='در محاسبه تغییر مکان ضریب زلزله حداقل جایگزین شد'
            end
            if T <= 0.5
                K = 1;
            elseif T <= 2.5
                K = 0.5 * T + 0.75;
            else
                K = 2;
            end
            
            if A==0.35 && Ie==1.4
                if ft==1
                    app.Label_13.Text='برای ساختمانهای با اهمیت زیاد در مناطق با خطر نسبی خیلی زیاد فقط باید از سیستمهایی که عنوان ویژه دارند استفاده شود!'
                end
            end
            
            fun = @(x) sprintf('%0.3f', x);
            
            Result={};Result {1,1}=A;Result {1, 2} = Ie;Result {1, 3} =T;
            Result {1, 4} =T0;
            Result {1, 5} = Ts;Result {1, 6} = S0;Result {1, 7} = S;
            Result {1, 8} = Ru;Result {1, 9} = Omega0;Result {1, 10}=Cd;
            Result {1, 11} = N;Result {1, 12} = B1;Result {1, 13} =B;
            Result {1, 14} = Cmin;Result {1, 15} = C;Result {1, 16} =K;
            Result=cellfun(fun, Result, 'UniformOutput',0);
            
            ContRes={};
            ContRes{1, 1} =N;ContRes{1, 2} = B1;ContRes{1, 3} = B;
            ContRes{1, 4} = Cmin;ContRes{1, 5} =C;ContRes{1, 6} = K;
            ContRes=cellfun(fun, ContRes, 'UniformOutput',0);
            
            
            Header={'','*خروجی برنامه  برای دفترچه محاسبات*'};
            filename = 'RowName.txt';fileID = fopen(filename,'r');
            Tit = textscan(fileID,'%q %*q %*d %f','Delimiter',',');
            fclose(fileID);
            FR={};FR {1,1}=[H];FR {3,1}=[A];FR {5,1}=Ru;FR {6,1}=Omega0;
            FR {7,1}=Cd;FR {8,1}=Gt;FR {10,1}=T;FR {11,1}=T0;
            FR {12,1}=Ts;FR {13,1}=S0;FR {14,1}=S;FR {15,1}=N;
            FR {16,1}=B1;FR {17,1}=B; FR {18,1}=Cmin;FR {19,1}=C;
            FR {20,1}=K;FR {21,1}=C;FR {22,1}=K;
            FR=cellfun(fun, FR, 'UniformOutput',0);
            FR(:,2)=Tit{1};FR {9,1}=EffSwitch;FR {2,1}=pa;FR {4,1}=Fr;
            FR=[Header;FR];
            
            app.UITable2.Data=Result;app.UITable3.Data=ContRes;
            app.UITable5.Data   = FR;app.UITable5.FontName='B Nazanin';
            app.UITable5.FontSize=14;app.UITable5.ColumnWidth='auto';
            app.UITable5.ColumnEditable=[true];
        end

        % Button pushed function: ExportResultButton
        function ExportResultButtonPushed(app, event)
            FR=app.UITable5.Data;
            Rep=app.Label_13.Text;
            Desc=app.TextArea.Value;
            [filename filepath]= uiputfile({'*.xlsx','Excel File (.xlsx)';...
                '*.pdf','PDF File (.pdf)';...
                '*.docx','Word File (.docx)'},...
                'Select File to Save','Untitled.xlsx');
            if filename==0
                return
            end
            file=fullfile(filepath,filename);
            [~,~,ext]= fileparts(file);
            
            switch ext
                case '.xlsx'
                    xlswrite(file,FR);
                case '.pdf'
                    FR2=FR(2:23,:);
                    import mlreportgen.dom.*
                    d = Document(filename,"pdf");
                    open(d);
                    tableStyle = { Width("80%"), ...
                        Border("solid"), ...
                        RowSep("solid"), ...
                        ColSep("solid") };
                    headerStyle = { BackgroundColor("LightBlue"), ...
                        Bold(true) };
                    footerStyle = { BackgroundColor("LightCyan"), ...
                        ColSep("none"), ...
                        WhiteSpace("preserve") };
                    
                    headerContent = {'خروجی برنامه',' '};
                    bodyContent = FR2;
                    total = sum([bodyContent{:,2}]);
                    footerContent = Rep;
                    tableContent = [headerContent; bodyContent,footerContent];
                    %footerContent=Rep;
                    append(d,Heading1("*خروجی برنامه برای دفترچه محاسبات*"));
                    
                    table = FormalTable(headerContent,bodyContent,footercontent);
                    table.Style = tableStyle;
                    
                    firstRow = table.Children(1);
                    firstRow.Style = headerStyle;
                    table.TableEntriesHAlign = "center";
                    append(d, table);
                    close(d);
                    
                    OutputPath=filepath;
                    rptview(d.OutputPath);
                    
                case '.docx'
                    FR2=FR(2:23,:);
                    import mlreportgen.dom.*;
                    d = Document('filename','docx');headerContent = {'خروجی برنامه',' '};
                    bodyContent = FR2;
                    tableStyle = { Width("80%"), ...
                        Border("solid"), ...
                        RowSep("solid"), ...
                        ColSep("solid") };
                    headerStyle = { BackgroundColor("LightBlue"), ...
                        Bold(true) };
                    footerStyle = { BackgroundColor("LightCyan"), ...
                        ColSep("none"), ...
                        WhiteSpace("preserve") };
                    total = sum([bodyContent{:,2}]);
                    footerContent = {[],'Total ',total};
                    tableContent = [headerContent; bodyContent];
                    append(d,Heading1("*خروجی برنامه برای دفترچه محاسبات*"));
                    
                    table = Table(tableContent);
                    table.Style = tableStyle;
                    
                    firstRow = table.Children(1);
                    firstRow.Style = headerStyle;
                    table.TableEntriesHAlign = "center";
                    append(d, table);
                    close(d);
                    OutputPath=filepath;
                    rptview(d.OutputPath);
            end
            msgbox('The data of the table is writed to file successfully.',...
                'Writing Message','modal')
            
        end

        % Button pushed function: CalculateBButton
        function CalculateBButtonPushed(app, event)
            FR=app.UITable5.Data;
            
            A=str2num(FR{4,1});T0=str2num(FR{12,1});Ts=str2num(FR{13,1});
            S0=str2num(FR{14,1});S=str2num(FR{15,1});%T=str2num(FR{11,1});
            Spect=[];
            Spect(2,1)=0;Spect(3,1)=T0;Spect(4,1)=Ts;T=Spect(4,1);
            %             Spect(1,1)="T";
            %             Spect(2,1)="B"
            i=5;
            while T<6;
                T = T + 0.1;
                Spect(i, 1) = T;
                i = i + 1;
            end
            i=2;
            for nsor=1:length(Spect)-1
                
                T = Spect(i, 1);
                if A <= 0.25
                    if T <= Ts
                        N = 1;
                    elseif T < 4
                        N = (0.4 / (4 - Ts)) * (T - Ts) + 1;
                    else
                        N = 1.4;
                    end
                else
                    if T <= Ts
                        N = 1;
                    elseif T < 4
                        N = (0.7 / (4 - Ts)) * (T - Ts) + 1;
                    else
                        N = 1.7;
                    end
                end
                
                if T <= T0
                    B1 = S0 + (S - S0 + 1) * (T / T0);
                elseif T <= Ts
                    B1 = 1 + S;
                else
                    B1 = (S + 1) * (Ts / T);
                end
                B = B1 * N;
                Spect(i, 2) = B;
                i = i + 1;
                nsor=nsor+1;
                
            end
            
            app.UITable4.Data=Spect(2:length(Spect)-1,:);
            app.UITable4.ColumnWidth={64,64};
            plot(app.UIAxes,Spect(:,1),Spect(:,2),'linewidth',2,'markersize',20,'markerfacecolor','g')
            
        end

        % Button pushed function: ExportResultsButton
        function ExportResultsButtonPushed(app, event)
            Spect=app.UITable4.Data;
            
            SpectC=cellfun(@(x) sprintf('%0.3f', x), num2cell(Spect), 'UniformOutput',0);
            FR=app.UITable5.Data;
            T0=FR{12,1};Ts=FR{13,1};S0=FR{14,1};S=FR{15,1};
            Tit={'S0=',S0;'S=',S;'T0=',T0;'Ts=',Ts;'','';'T','B'}
            
            DisplayResult=[Tit;SpectC];
            
            [filename filepath]= uiputfile({'*.xlsx','Excel File (.xlsx)';...
                '*.pdf','PDF File (.pdf)';...
                '*.docx','Word File (.docx)'},...
                'Select File to Save','Untitled.xlsx');
            if filename==0
                return
            end
            file=fullfile(filepath,filename);
            [~,~,ext]= fileparts(file);
            
            
            switch ext
                case '.xlsx'
                    xlswrite(file,DisplayResult);
                case '.pdf'
                    
                    import mlreportgen.dom.*
                    d = Document(filename,"pdf");
                    open(d);
                    tableStyle = { Width("80%"), ...
                        Border("solid"), ...
                        RowSep("solid"), ...
                        ColSep("solid") };
                    headerStyle = { BackgroundColor("LightBlue"), ...
                        Bold(true) };
                    footerStyle = { BackgroundColor("LightCyan"), ...
                        ColSep("none"), ...
                        WhiteSpace("preserve") };
                    
                    headerContent = {'خروجی برنامه',' '};
                    bodyContent = DisplayResult;
                    total = sum([bodyContent{:,2}]);
                    tableContent = [headerContent; bodyContent,];
                    append(d,Heading1("*Spectrum Output*"));
                    
                    table = Table(tableContent);
                    table.Style = tableStyle;
                    
                    firstRow = table.Children(1);
                    firstRow.Style = headerStyle;
                    table.TableEntriesHAlign = "center";
                    append(d, table);
                    close(d);
                    
                    OutputPath=filepath;
                    rptview(d.OutputPath);
                    
                case '.docx'
                    import mlreportgen.dom.*;
                    d = Document('filename','docx');headerContent = {'خروجی برنامه',' '};
                    bodyContent = DisplayResult;
                    tableStyle = { Width("80%"), ...
                        Border("solid"), ...
                        RowSep("solid"), ...
                        ColSep("solid") };
                    headerStyle = { BackgroundColor("LightBlue"), ...
                        Bold(true) };
                    footerStyle = { BackgroundColor("LightCyan"), ...
                        ColSep("none"), ...
                        WhiteSpace("preserve") };
                    total = sum([bodyContent{:,2}]);
                    footerContent = {[],'Total ',total};
                    tableContent = [headerContent; bodyContent];
                    append(d,Heading1("*خروجی برنامه برای دفترچه محاسبات*"));
                    
                    table = Table(tableContent);
                    table.Style = tableStyle;
                    
                    firstRow = table.Children(1);
                    firstRow.Style = headerStyle;
                    table.TableEntriesHAlign = "center";
                    append(d, table);
                    close(d);
                    OutputPath=filepath;
                    rptview(d.OutputPath);
            end
            msgbox('The data of the table is writed to file successfully.',...
                'Writing Message','modal')
        end

        % Button pushed function: ClearTableButton
        function ClearTableButtonPushed(app, event)
            answerAT=questdlg('Are you sure about Clearing the data of the table?',...
                'Clearing Question','Yes','No','No');
            if strcmp(answerAT,'Yes')
                app.UITable4.Data={};
                cla(app.UIAxes)
            end
        end

        % Button pushed function: ClearResultButton
        function ClearResultButtonPushed(app, event)
            answerAT=questdlg('Are you sure about Clearing the data of the table?',...
                'Clearing Question','Yes','No','No');
            if strcmp(answerAT,'Yes')
                app.UITable5.Data={};
            end
        end

        % Button pushed function: Button_3
        function Button_3Pushed(app, event)
            answerAT=questdlg('Are you sure about Clearing the data of the table?',...
                'Clearing Question','Yes','No','No');
            if strcmp(answerAT,'Yes')
                app.UITable3.Data={};
                app.UITable2.Data={};
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.Label = uilabel(app.MainTab);
            app.Label.Text = 'ارتفاع ساختمان از تراز پایه -متر';
            app.EditField = uieditfield(app.MainTab, 'numeric');
            app.Label_2 = uilabel(app.MainTab);
            app.Label_2.Text = 'پهنه خطر لرزه ای';
            app.SiecmecZone = uidropdown(app.MainTab);
            app.SiecmecZone.Items = {'پهنه با خطر نسبی خیلی زیاد', 'پهنه با خطر نسبی  زیاد', 'پهنه با خطر نسبی متوسط', 'پهنه با خطر نسبی کم'};
            app.SiecmecZone.Value = 'پهنه با خطر نسبی متوسط';
            app.Label_3 = uilabel(app.MainTab);
            app.Label_3.Text = {'نوع سیستم مقاوم '; 'در برابر نیروهای جانبی'};
            app.FrameType = uidropdown(app.MainTab);
            app.FrameType.Items = {'1- دیوارهای برشی بتن آرمه ویژه', '2- دیوارهای برشی بتن آرمه متوسط', '3- دیوارهای برشی بتن آرمه معمولی', '4- دیوارهای برشی یا مصالح بنایی مسلح', '5- مهاربند واگرای ویژه فولادی', '6-مهاربندی کمانش تاب', '7- مهاربند همگرای معمولی فولادی', '8- مهاربند همگرای ویژه فولادی', '1- قاب خمشی بتن آرمه ویژه', '2- قاب خمشی بتن آرمه متوسط', '3- قاب خمشی بتن آرمه معمولی', '4- قاب خمشی فولادی ویژه', '5- قاب خمشی فولادی متوسط', '6- قاب خمشی فولادی معمولی', '1- قاب خمشی ویژه(فولادی یا بتنی)+ دیوارهای برشی بتن آرمه ویژه', '2- قاب خمشی بتن آرمه متوسط+ دیوارهای برشی بتن آرمه ویژه', '3- قاب خمشی بتن آرمه متوسط+ دیوارهای برشی بتن آرمه متوسط', '4- قاب خمشی فولادی متوسط+ دیوارهای برشی بتن آرمه متوسط', '5- قاب خمشی فولادی ویژه+ مهاربند واگرای ویژه', '6- قاب خمشی فولادی متوسط+ مهاربند واگرای ویژه', '7- قاب خمشی فولادی ویژه+ مهاربند همگرای ویژه', '8- قاب خمشی فولادی متوسط+ مهاربند همگرای ویژه', '1-سازه های فولادی یا بتن آرمه ویژه'};
            app.FrameType.Value = '1- دیوارهای برشی بتن آرمه ویژه';
            app.Label_4 = uilabel(app.MainTab);
            app.Label_4.Text = {'ضریب اهمیت ساختمان'; ''};
            app.ImportantLevel = uidropdown(app.MainTab);
            app.ImportantLevel.Items = {'با اهمیت خیلی زیاد', 'با اهمیت زیاد', 'با اهمیت متوسط', 'با اهمیت کم'};
            app.ImportantLevel.Value = 'با اهمیت خیلی زیاد';
            app.Label_5 = uilabel(app.MainTab);
            app.Label_5.Text = 'نوع زمین';
            app.LandType = uidropdown(app.MainTab);
            app.LandType.Items = {'I', 'II', 'III', 'IV'};
            app.LandType.Value = 'I';
            app.Label_11 = uilabel(app.MainTab);
            app.Label_11.Text = {'اثر میان قاب لحاظ شود؟'; ''};
            app.EffectSwitch = uiswitch(app.MainTab, 'slider');
            app.EffectSwitch.Items = {'خیر', 'بله'};
            app.EffectSwitch.Value = 'خیر';
            app.Label_12 = uilabel(app.MainTab);
            app.Label_12.Text = {'پریود تحلیلی ساختمان'; ''};
            app.Slider_2 = uislider(app.MainTab);
            app.Slider_2.Limits = [0 10];
            app.Slider_2.ValueChangedFcn = createCallbackFcn(app, @Slider_2ValueChanged, true);
            app.EditField_2 = uieditfield(app.MainTab, 'numeric');
            app.EditField_2.Limits = [0 10];
            app.EditField_2.ValueDisplayFormat = '%.3f';
            app.EditField_2.ValueChangedFcn = createCallbackFcn(app, @EditField_2ValueChanged, true);
            app.UITable2 = uitable(app.MainTab);
            app.UITable2.ColumnName = {'A'; 'I'; 'T'; 'T0'; 'Ts'; 'S0'; 'S'; 'Ru'; 'Ω0'; 'Cd'; 'N'; 'B1'; 'B'; 'Cmin'; 'C'; 'K'};
            app.UITable2.RowName = {};
            app.Button_2 = uibutton(app.MainTab, 'push');
            app.Button_2.ButtonPushedFcn = createCallbackFcn(app, @Button_2Pushed, true);
            app.Button_2.Text = 'محاسبه';
            app.UITable3 = uitable(app.MainTab);
            app.UITable3.ColumnName = {'N'; 'B1'; 'B'; 'Cmin'; 'C'; 'K'};
            app.UITable3.RowName = {};
            app.UITable3.ColumnEditable = true;
            app.Label_9 = uilabel(app.MainTab);
            app.Label_9.Text = 'برای کنترل تغییرمکان';
            app.Label_8 = uilabel(app.MainTab);
            app.Label_8.Text = 'برای طراحی';
            app.Button_3 = uibutton(app.MainTab, 'push');
            app.Button_3.ButtonPushedFcn = createCallbackFcn(app, @Button_3Pushed, true);
            app.Button_3.Text = 'پاک کردن جدول';
            app.ProgramOutputTab = uitab(app.TabGroup);
            app.ProgramOutputTab.Title = 'Program Output';
            app.ExportResultButton = uibutton(app.ProgramOutputTab, 'push');
            app.ExportResultButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultButtonPushed, true);
            app.ExportResultButton.Text = 'Export Result';
            app.UITable5 = uitable(app.ProgramOutputTab);
            app.UITable5.ColumnName = '';
            app.UITable5.RowName = {};
            app.ClearResultButton = uibutton(app.ProgramOutputTab, 'push');
            app.ClearResultButton.ButtonPushedFcn = createCallbackFcn(app, @ClearResultButtonPushed, true);
            app.ClearResultButton.Text = 'Clear Result';
            app.SpectrumTab = uitab(app.TabGroup);
            app.SpectrumTab.Title = 'Spectrum';
            app.UITable4 = uitable(app.SpectrumTab);
            app.UITable4.ColumnName = {'T'; 'B'};
            app.UITable4.RowName = {};
            app.ClearTableButton = uibutton(app.SpectrumTab, 'push');
            app.ClearTableButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTableButtonPushed, true);
            app.ClearTableButton.Text = 'Clear Table';
            app.ExportResultsButton = uibutton(app.SpectrumTab, 'push');
            app.ExportResultsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportResultsButtonPushed, true);
            app.ExportResultsButton.Text = 'Export Results';
            app.CalculateBButton = uibutton(app.SpectrumTab, 'push');
            app.CalculateBButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateBButtonPushed, true);
            app.CalculateBButton.Text = 'Calculate B';
            app.UIAxes = uiaxes(app.SpectrumTab);
            xlabel(app.UIAxes, 'B')
            ylabel(app.UIAxes, 'T')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XLim = [0 7];
            app.UIAxes.YLim = [0 3];
            app.UIAxes.ZLim = [0 3];
            app.AboutTab = uitab(app.TabGroup);
            app.AboutTab.Title = 'About';
            app.Image = uiimage(app.AboutTab);
            app.Image.ImageSource = 'logo-mohr.png';
            app.SAYEHGroupLabel = uilabel(app.AboutTab);
            app.SAYEHGroupLabel.Text = 'SAYEH Group';
            app.Label_14 = uilabel(app.AboutTab);
            app.Label_14.Text = {'نرم افزار محاسبه پارامتر های زلزله مطابق بر ویرایش چهارم استاندارد 2800 ، پس از تعیین پارامترهای ارتفاع سازه،'; ' نوع زمین، منطقه لرزه خیزی، سیستم سازه و سیستم مقاوم در برابر نیروی جانبی،کلیه پارامتر های محاسباتی نظیر '; 'زمان تناوب سازه، ضریب زلزله و ضریب ارتفاع و … را بر اساس ویرایش چهارم آیین نامه 2800 محاسبه مینماید.'};
            app.DeveloperMostafaJafariLabel = uilabel(app.AboutTab);
            app.DeveloperMostafaJafariLabel.Text = {'Developer:'; 'Mostafa Jafari'};
            app.wwwSha2wirenLabel = uilabel(app.AboutTab);
            app.wwwSha2wirenLabel.Text = 'www.Sha2w.ir/en';
            app.Label_15 = uilabel(app.AboutTab);
            app.Label_15.Text = '+989117569002';
            app.Label_16 = uilabel(app.AboutTab);
            app.Label_16.Text = {'The module currently supports calculations based on the Iranian code of practice for '; 'seismic resistant design of buildings (Standard No. 2800-V4).'};
            app.Label_13 = uilabel(app.UIFigure);
            app.Label_13.Text = ' ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Eq2800

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end